classdef rotation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        d1SliderLabel              matlab.ui.control.Label
        d1Slider                   matlab.ui.control.Slider
        d2SliderLabel              matlab.ui.control.Label
        d2Slider                   matlab.ui.control.Slider
        d3SliderLabel              matlab.ui.control.Label
        d3Slider                   matlab.ui.control.Slider
        eulerparameterLabel        matlab.ui.control.Label
        phiSliderLabel             matlab.ui.control.Label
        phiSlider                  matlab.ui.control.Slider
        quaternionsEditFieldLabel  matlab.ui.control.Label
        quaternionsEditField       matlab.ui.control.EditField
        rodriguessEditFieldLabel   matlab.ui.control.Label
        rodriguessEditField        matlab.ui.control.EditField
        S12                        matlab.ui.control.EditField
        S13                        matlab.ui.control.EditField
        S21                        matlab.ui.control.EditField
        S22                        matlab.ui.control.EditField
        S23                        matlab.ui.control.EditField
        S31                        matlab.ui.control.EditField
        S32                        matlab.ui.control.EditField
        S33                        matlab.ui.control.EditField
        SLabel                     matlab.ui.control.Label
        S11                        matlab.ui.control.EditField
        RightPanel                 matlab.ui.container.Panel
        mainAxis                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        
        d;
        phi;
        
        e1; e2; e3; % coordinates of initial coordinate frame
        e_1; e_2; e_3; % coordinates of transformed coordinate frame
        dline;
    end
    
    methods (Access = private)
        
        function updateAxis(app,S)
            b_I = eye(3); % axis in initial coordinate frame
            
            % transform coordinate frame
            b_R = S*b_I; 
            % draw coordinate frame and transformed coordinate frame
            ax = app.mainAxis;
            
            if( isempty(app.e1) )
                % coordinate frame has never been drawn
                
                [app.e1, app.e2, app.e3] = app.drawCoordinateFrame(b_I,'--');
                [app.e_1, app.e_2, app.e_3] = app.drawCoordinateFrame(b_R,'-');
                app.dline = line(ax,[0,app.d(1)],[0,app.d(2)],[0,app.d(3)],'Color','k','LineWidth',1.5);

            else
                % update only transformed coordinate frame
                app.updateCoordinate(app.e_1,b_R(:,1));
                app.updateCoordinate(app.e_2,b_R(:,2));
                app.updateCoordinate(app.e_3,b_R(:,3));
                app.updateCoordinate(app.dline,app.d(:));
            end
            
        end
        
        function [e1,e2,e3] = drawCoordinateFrame(app,K,linestyle)
            ax = app.mainAxis;
            
            e1 = line(ax,[0,K(1,1)],[0,K(2,1)],[0,K(3,1)],'Color','r','LineStyle',linestyle);
            e2 = line(ax,[0,K(1,2)],[0,K(2,2)],[0,K(3,2)],'Color','g','LineStyle',linestyle);
            e3 = line(ax,[0,K(1,3)],[0,K(2,3)],[0,K(3,3)],'Color','b','LineStyle',linestyle);
            
        end
        
        function updateCoordinate(app,l,e)
            set(l,'XData',[0,e(1)]); 
            set(l,'YData',[0,e(2)]); 
            set(l,'ZData',[0,e(3)]); 
        end
        
        function updateSlider(app)
            % get values of slider
            d1 = app.d1Slider.Value;
            d2 = app.d2Slider.Value;
            d3 = app.d3Slider.Value;
            
            d = [d1;d2;d3];
            app.d = d/norm(d);
            app.phi = app.phiSlider.Value;
            
            % rotation matrix
            S = Rodriguess(app.d,app.phi);
            
            % update axis
            app.updateAxis(S);
            
            % update Edit fields
            q = euler2Quaternions(app.d,app.phi);
            quaternions = sprintf('%.3f,' , q);
            quaternions = quaternions(1:end-1); % remove final comma
            app.quaternionsEditField.Value = string(quaternions);
            
            rodriguess= sprintf('%.3f,' , quaternions2Rodriguess(q));
            rodriguess= rodriguess(1:end-1); % remove final comma
            app.rodriguessEditField.Value = string(rodriguess);
            
            RotationEditField = {app.S11,app.S12,app.S13;app.S21,app.S22,app.S23;app.S31,app.S32,app.S33};
            for ii = 1:3
                for jj = 1:3
                    RotationEditField{ii,jj}.Value = sprintf('%.3f' , S(ii,jj));
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.updateSlider;
            
            view(app.mainAxis,[1,1,1]);
            zlabel(app.mainAxis,"Z");
            
        end

        % Value changed function: d1Slider, d2Slider, d3Slider, 
        % phiSlider
        function sliderValueChanged(app, event)
            app.updateSlider;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {484, 484};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {287, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.d1SliderLabel = uilabel(app.LeftPanel);
            app.d1SliderLabel.Text = 'd1';
            app.d1Slider = uislider(app.LeftPanel);
            app.d1Slider.Limits = [-1 1];
            app.d1Slider.ValueChangedFcn = createCallbackFcn(app, @sliderValueChanged, true);
            app.d2SliderLabel = uilabel(app.LeftPanel);
            app.d2SliderLabel.Text = 'd2';
            app.d2Slider = uislider(app.LeftPanel);
            app.d2Slider.Limits = [-1 1];
            app.d2Slider.ValueChangedFcn = createCallbackFcn(app, @sliderValueChanged, true);
            app.d3SliderLabel = uilabel(app.LeftPanel);
            app.d3SliderLabel.Text = 'd3';
            app.d3Slider = uislider(app.LeftPanel);
            app.d3Slider.Limits = [-1 1];
            app.d3Slider.ValueChangedFcn = createCallbackFcn(app, @sliderValueChanged, true);
            app.d3Slider.Value = 1;
            app.eulerparameterLabel = uilabel(app.LeftPanel);
            app.eulerparameterLabel.Text = 'euler parameter';
            app.phiSliderLabel = uilabel(app.LeftPanel);
            app.phiSliderLabel.Text = 'phi';
            app.phiSlider = uislider(app.LeftPanel);
            app.phiSlider.Limits = [-3.14159265358979 3.14159265358979];
            app.phiSlider.ValueChangedFcn = createCallbackFcn(app, @sliderValueChanged, true);
            app.phiSlider.Value = 0.785398163397448;
            app.quaternionsEditFieldLabel = uilabel(app.LeftPanel);
            app.quaternionsEditFieldLabel.Text = 'quaternions';
            app.quaternionsEditField = uieditfield(app.LeftPanel, 'text');
            app.quaternionsEditField.Editable = 'off';
            app.rodriguessEditFieldLabel = uilabel(app.LeftPanel);
            app.rodriguessEditFieldLabel.Text = 'rodriguess';
            app.rodriguessEditField = uieditfield(app.LeftPanel, 'text');
            app.rodriguessEditField.Editable = 'off';
            app.S11 = uieditfield(app.LeftPanel, 'text');
            app.S11.Editable = 'off';
            app.S12 = uieditfield(app.LeftPanel, 'text');
            app.S12.Editable = 'off';
            app.S13 = uieditfield(app.LeftPanel, 'text');
            app.S13.Editable = 'off';
            app.S21 = uieditfield(app.LeftPanel, 'text');
            app.S21.Editable = 'off';
            app.S22 = uieditfield(app.LeftPanel, 'text');
            app.S22.Editable = 'off';
            app.S23 = uieditfield(app.LeftPanel, 'text');
            app.S23.Editable = 'off';
            app.S31 = uieditfield(app.LeftPanel, 'text');
            app.S31.Editable = 'off';
            app.S32 = uieditfield(app.LeftPanel, 'text');
            app.S32.Editable = 'off';
            app.S33 = uieditfield(app.LeftPanel, 'text');
            app.S33.Editable = 'off';
            app.SLabel = uilabel(app.LeftPanel);
            app.SLabel.Text = 'S';
            app.RightPanel = uipanel(app.GridLayout);
            app.mainAxis = uiaxes(app.RightPanel);
            title(app.mainAxis, 'vector in inertial coordinate frame and rotated coordinate frame')
            xlabel(app.mainAxis, 'X')
            ylabel(app.mainAxis, 'Y')
            app.mainAxis.XLim = [-1.1 1.1];
            app.mainAxis.YLim = [-1.1 1.1];
            app.mainAxis.ZLim = [-1.1 1.1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rotation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end