classdef animation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        tSliderLabel  matlab.ui.control.Label
        tSlider       matlab.ui.control.Slider
        startButton   matlab.ui.control.Button
        pauseButton   matlab.ui.control.Button
        saveButton    matlab.ui.control.Button
    end

    
    properties (Access = private)
        bf; % Description
        current_frame = 1;
        num_frames;
        pause_flag = false;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, radius, thickness, position, q, t_span)
            
            app.bf = BicycleWheel(radius,thickness,position,q,t_span);
            app.num_frames = length(t_span);
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            app.pause_flag = false;
            for ii = app.current_frame:app.num_frames
                
                app.bf.plot(ii)
                
                pause(app.bf.t_span(2))
                
                app.current_frame = ii;
                app.tSlider.Value = round(100*ii/app.num_frames);
                
                % check if pause button was pressed
                if (app.pause_flag == true)
                    break;
                end
            end
        end

        % Button pushed function: pauseButton
        function pauseButtonPushed(app, event)
            app.pause_flag = true;
        end

        % Value changed function: tSlider
        function tSliderValueChanged(app, event)
            value = app.tSlider.Value;
            
            app.current_frame = max(round(value/100*app.num_frames),1);
            app.bf.plot(app.current_frame);
            
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            frameRate = 30;
            stepsize = round(app.num_frames/(app.bf.t_span(end)*frameRate));
            
            writerObj = VideoWriter('figures/animation.mp4');
            writerObj.FrameRate = frameRate;
             
            % open the video writer
            open(writerObj);
            for ii = 1:stepsize:app.num_frames
                app.bf.plot(ii)
                % write the frames to the video
                F = getframe(app.bf.fig) ;
                writeVideo(writerObj, F);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.tSliderLabel = uilabel(app.UIFigure);
            app.tSliderLabel.Text = 't';
            app.tSlider = uislider(app.UIFigure);
            app.tSlider.ValueChangedFcn = createCallbackFcn(app, @tSliderValueChanged, true);
            app.startButton = uibutton(app.UIFigure, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.pauseButton = uibutton(app.UIFigure, 'push');
            app.pauseButton.ButtonPushedFcn = createCallbackFcn(app, @pauseButtonPushed, true);
            app.pauseButton.Text = 'pause';
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = animation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end